classdef MPC_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Main_Window                matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        RunMPCButton               matlab.ui.control.Button
        Panel                      matlab.ui.container.Panel
        Model_Data_Input_Name      matlab.ui.control.EditField
        Panel_2                    matlab.ui.container.Panel
        DynamicHatLabel            matlab.ui.control.Label
        Dynamic_Hat_Input_Name     matlab.ui.control.EditField
        RealDynamicfLabel          matlab.ui.control.Label
        Dynamic_Real_Input_Name    matlab.ui.control.EditField
        PathConstLabel             matlab.ui.control.Label
        Path_Const_Input_Name      matlab.ui.control.EditField
        Image42                    matlab.ui.control.Image
        Panel_3                    matlab.ui.container.Panel
        TsLabel                    matlab.ui.control.Label
        EditField_2                matlab.ui.control.NumericEditField
        EditField_3Label           matlab.ui.control.Label
        EditField_3                matlab.ui.control.NumericEditField
        EditField_4Label           matlab.ui.control.Label
        EditField_4                matlab.ui.control.NumericEditField
        EditField_5Label           matlab.ui.control.Label
        EditField_5                matlab.ui.control.NumericEditField
        EditField_6Label           matlab.ui.control.Label
        EditField_6                matlab.ui.control.NumericEditField
        EditField_7Label           matlab.ui.control.Label
        EditField_7                matlab.ui.control.EditField
        DynamicSolverMethodLabel   matlab.ui.control.Label
        ListBox                    matlab.ui.control.ListBox
        Image15                    matlab.ui.control.Image
        Image16                    matlab.ui.control.Image
        Image17                    matlab.ui.control.Image
        Image18                    matlab.ui.control.Image
        Image19                    matlab.ui.control.Image
        Image20                    matlab.ui.control.Image
        Image21                    matlab.ui.control.Image
        Panel_4                    matlab.ui.container.Panel
        EditField_8Label           matlab.ui.control.Label
        EditField_8                matlab.ui.control.NumericEditField
        EditField_9Label           matlab.ui.control.Label
        EditField_9                matlab.ui.control.EditField
        EditField_10Label          matlab.ui.control.Label
        EditField_10               matlab.ui.control.EditField
        EditField_11Label          matlab.ui.control.Label
        EditField_11               matlab.ui.control.NumericEditField
        EditField_12Label          matlab.ui.control.Label
        EditField_12               matlab.ui.control.NumericEditField
        EditField_13Label          matlab.ui.control.Label
        EditField_13               matlab.ui.control.NumericEditField
        EditField_14Label          matlab.ui.control.Label
        EditField_14               matlab.ui.control.NumericEditField
        EditField_15Label          matlab.ui.control.Label
        EditField_15               matlab.ui.control.NumericEditField
        Image22                    matlab.ui.control.Image
        Image23                    matlab.ui.control.Image
        Image24                    matlab.ui.control.Image
        Image27                    matlab.ui.control.Image
        Image28                    matlab.ui.control.Image
        Image29                    matlab.ui.control.Image
        Image25                    matlab.ui.control.Image
        Image43                    matlab.ui.control.Image
        Panel_5                    matlab.ui.container.Panel
        KalmanFlagSwitch           matlab.ui.control.Switch
        Label_4                    matlab.ui.control.Label
        Kalman_Data_Input_Name     matlab.ui.control.EditField
        Kalman_P_Input_Name        matlab.ui.control.EditField
        Label_3                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Image30                    matlab.ui.control.Image
        Panel_7                    matlab.ui.container.Panel
        Plot_typeListBox           matlab.ui.control.ListBox
        Label                      matlab.ui.control.Label
        Plot_indxEditField         matlab.ui.control.NumericEditField
        Label_2                    matlab.ui.control.Label
        Image13                    matlab.ui.control.Image
        Image14                    matlab.ui.control.Image
        UpdateAllParametersButton  matlab.ui.control.Button
        Panel_6                    matlab.ui.container.Panel
        EditField                  matlab.ui.control.EditField
        PlotButton                 matlab.ui.control.Button
        Image2                     matlab.ui.control.Image
        Image3                     matlab.ui.control.Image
        Image4                     matlab.ui.control.Image
        Image5                     matlab.ui.control.Image
        Image6                     matlab.ui.control.Image
        Image7                     matlab.ui.control.Image
        Image9                     matlab.ui.control.Image
        Image11                    matlab.ui.control.Image
        Image12                    matlab.ui.control.Image
        Image31                    matlab.ui.control.Image
        Image32                    matlab.ui.control.Image
        Image33                    matlab.ui.control.Image
        Image38                    matlab.ui.control.Image
        Image39                    matlab.ui.control.Image
        Image41                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        Model_data % Description
        Dynamics
        PMC_params
        fmincon_options
        Kalman
        plot_info
        Time_Out
        Unscaled_States_Out_Real
        Unscaled_States_Out_hat
        Unscaled_Controls_Out
        auxdata
        saved_address
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.Dynamic_Hat_Input_Name.Interpreter = 'latex';
            %app.Dynamic_Hat_Input_Name.Text = '$\theta$ of point';
        end

        % Value changed function: Model_Data_Input_Name
        function Model_Data_Input_NameValueChanged(app, event)
            value = app.Model_Data_Input_Name.Value;
            Model_data_struct=load(value);
            app.Model_data=Model_data_struct.Model_data;
        end

        % Value changed function: Dynamic_Hat_Input_Name
        function Dynamic_Hat_Input_NameValueChanged(app, event)
            value = str2func(app.Dynamic_Hat_Input_Name.Value);
            
            %app.Dynamics.Dynamic_hat_func=@(t,q_scaled_with_obj,U_scaled,auxdata) Dynamic_hat(t,q_scaled_with_obj,U_scaled,auxdata);
            app.Dynamics.Dynamic_hat_func=@(t,q_scaled_with_obj,U_scaled,auxdata) value(t,q_scaled_with_obj,U_scaled,auxdata);
        end

        % Value changed function: Dynamic_Real_Input_Name
        function Dynamic_Real_Input_NameValueChanged(app, event)
            value = str2func(app.Dynamic_Real_Input_Name.Value);
            app.Dynamics.Dynamic_Real_func=@(t,q_scaled_with_obj,U_scaled,auxdata) value(t,q_scaled_with_obj,U_scaled,auxdata);
            %app.Dynamics.Dynamic_Real_func=@(t,q_scaled_with_obj,U_scaled,auxdata) Dynamic_Real(t,q_scaled_with_obj,U_scaled,auxdata);
        end

        % Value changed function: Path_Const_Input_Name
        function Path_Const_Input_NameValueChanged(app, event)
            value = str2func(app.Path_Const_Input_Name.Value);
            app.Dynamics.Path_Constraint_func=@(t,q_scaled,auxdata) value(t,q_scaled,auxdata);
            %app.Dynamics.Path_Constraint_func=@(t,q_scaled,auxdata) Path_constraint(t,q_scaled,auxdata);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.PMC_params.Ts=value;
            %app.PMC_params.Ts=1;
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.PMC_params.m=value;
            %app.PMC_params.m=2;
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.PMC_params.p=value;
            %app.PMC_params.p=2;
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.PMC_params.h=value;
            %app.PMC_params.h=0.1;
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            app.PMC_params.constraint_step_time=max(value,app.PMC_params.h);
            %app.PMC_params.constraint_step_time=0.1;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.PMC_params.dynamic_solver_method=str2num(value);
            %app.PMC_params.dynamic_solver_method=1;
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            value = app.EditField_8.Value;
            app.fmincon_options.number_of_cores=value;
            %app.fmincon_options.number_of_cores=1;
            if app.fmincon_options.number_of_cores>1
                parpool(app.fmincon_options.number_of_cores);
                app.fmincon_options.parallel_flag=true;
            else
                app.fmincon_options.parallel_flag=false;
            end
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;
            app.fmincon_options.TolCon=value;
            %app.fmincon_options.TolCon=1e-9;
        end

        % Value changed function: EditField_12
        function EditField_12ValueChanged(app, event)
            value = app.EditField_12.Value;
            app.fmincon_options.TolFun=value;
            %app.fmincon_options.TolFun=1e-9;
        end

        % Value changed function: EditField_13
        function EditField_13ValueChanged(app, event)
            value = app.EditField_13.Value;
            app.fmincon_options.MaxFunEvals=value;
            %app.fmincon_options.MaxFunEvals=1e7;
        end

        % Value changed function: EditField_14
        function EditField_14ValueChanged(app, event)
            value = app.EditField_14.Value;
            app.fmincon_options.StepTolerance=value;
            %app.fmincon_options.StepTolerance=1e-6;
        end

        % Value changed function: EditField_15
        function EditField_15ValueChanged(app, event)
            value = app.EditField_15.Value;
            app.fmincon_options.MaxIter=value;
            %app.fmincon_options.MaxIter=100;
            
            app.fmincon_options.optimoptions=optimoptions(@fmincon,...
                'Algorithm',app.fmincon_options.Algorithm, ...
                'display',app.fmincon_options.display,...
                'TolCon',app.fmincon_options.TolCon,...
                'TolFun',app.fmincon_options.TolFun,...
                'MaxFunEvals',app.fmincon_options.MaxFunEvals,...
                'StepTolerance',app.fmincon_options.StepTolerance,...
                'MaxIter',app.fmincon_options.MaxIter,...
                'UseParallel',app.fmincon_options.parallel_flag);            
        end

        % Value changed function: KalmanFlagSwitch
        function KalmanFlagSwitchValueChanged(app, event)
            value = app.KalmanFlagSwitch.Value;
            app.Kalman.flag=strcmp(value,'On');
            
            %app.Kalman.flag=0;
            if app.Kalman.flag==1
                value=app.Kalman_Data_Input_Name.Value;
                Kalman_data_struct=load(value);
                app.Kalman=Kalman_data_struct.Kalman;
                
                value = str2func(app.Kalman_P_Input_Name.Value);
                app.Kalman.P_dynamics=@(t,q,auxdata) value(t,q,auxdata);     
            end     
        end

        % Button pushed function: RunMPCButton
        function RunMPCButtonPushed(app, event)
             [app.Time_Out,...
             app.Unscaled_States_Out_Real,...
             app.Unscaled_States_Out_hat,...
             app.Unscaled_Controls_Out,...
             app.auxdata] = RunMPC_func(app.Model_data,...
                                    app.Dynamics,...
                                    app.PMC_params,...
                                    app.Kalman,...
                                    app.fmincon_options);
            app_Location = uigetdir; 
            Result.Time_Out = app.Time_Out; 
            Result.Unscaled_States_Out_Real = app.Unscaled_States_Out_Real; 
            Result.Unscaled_States_Out_hat = app.Unscaled_States_Out_hat; 
            Result.Unscaled_Controls_Out = app.Unscaled_Controls_Out; 
            Result.auxdata = app.auxdata; 
            save(fullfile(app_Location, app.saved_address), 'Result');
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            value = app.EditField_7.Value;
            value_vec=str2num(value).';
            app.PMC_params.x0_init=value_vec;
            %app.PMC_params.x0_init=[0 -1].';
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            value = app.EditField_9.Value;
            app.fmincon_options.Algorithm=value;
            %app.fmincon_options.Algorithm='interior-point';
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            value = app.EditField_10.Value;
            app.fmincon_options.display=value;
            %app.fmincon_options.display='iter';
        end

        % Value changed function: Plot_typeListBox
        function Plot_typeListBoxValueChanged(app, event)
            value = app.Plot_typeListBox.Value;
            app.plot_info.type=str2num(value);
        end

        % Value changed function: Plot_indxEditField
        function Plot_indxEditFieldValueChanged(app, event)
            value = app.Plot_indxEditField.Value;
            app.plot_info.plot_index=value;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            x=app.Time_Out;
            if app.plot_info.type==0
                y=app.Unscaled_Controls_Out(:,app.plot_info.plot_index);
            elseif app.plot_info.type==1
                y=app.Unscaled_States_Out_Real(:,app.plot_info.plot_index);
            elseif app.plot_info.type==2
                y=app.Unscaled_States_Out_hat(:,app.plot_info.plot_index);
            end
            plot(app.UIAxes,x,y);
            
            h = app.UIAxes.Children;
            % get handle of a single line plot
            line1 = h(1);
            % change the properties of the line plot based on user input.
            %line1.Color = 'r';
            line1.LineWidth = 2;
            
        end

        % Button pushed function: UpdateAllParametersButton
        function UpdateAllParametersButtonPushed(app, event)
            
            value = app.Model_Data_Input_Name.Value;
            Model_data_struct=load(value);
            app.Model_data=Model_data_struct.Model_data;
            
            value = str2func(app.Dynamic_Hat_Input_Name.Value);
            app.Dynamics.Dynamic_hat_func=@(t,q_scaled_with_obj,U_scaled,auxdata) value(t,q_scaled_with_obj,U_scaled,auxdata);
            value = str2func(app.Dynamic_Real_Input_Name.Value);
            app.Dynamics.Dynamic_Real_func=@(t,q_scaled_with_obj,U_scaled,auxdata) value(t,q_scaled_with_obj,U_scaled,auxdata);
            value = str2func(app.Path_Const_Input_Name.Value);
            app.Dynamics.Path_Constraint_func=@(t,q_scaled,auxdata) value(t,q_scaled,auxdata);
            
            value = app.EditField_2.Value;
            app.PMC_params.Ts=value;
            value = app.EditField_3.Value;
            app.PMC_params.m=value;
            value = app.EditField_4.Value;
            app.PMC_params.p=value;
            value = app.EditField_5.Value;
            app.PMC_params.h=value;
            value = app.EditField_6.Value;
            app.PMC_params.constraint_step_time=max(value,app.PMC_params.h);
            value = app.ListBox.Value;
            app.PMC_params.dynamic_solver_method=str2num(value);
            value = app.EditField_7.Value;
            value_vec=str2num(value).';
            app.PMC_params.x0_init=value_vec;
            
            value = app.EditField_8.Value;
            app.fmincon_options.number_of_cores=value;
            %app.fmincon_options.number_of_cores=1;
            if app.fmincon_options.number_of_cores>1
                parpool(app.fmincon_options.number_of_cores);
                app.fmincon_options.parallel_flag=true;
            else
                app.fmincon_options.parallel_flag=false;
            end
            value = app.EditField_11.Value;
            app.fmincon_options.TolCon=value;
            value = app.EditField_12.Value;
            app.fmincon_options.TolFun=value;
            value = app.EditField_13.Value;
            app.fmincon_options.MaxFunEvals=value;
            value = app.EditField_14.Value;
            app.fmincon_options.StepTolerance=value;
            value = app.EditField_15.Value;
            app.fmincon_options.MaxIter=value;
            value = app.EditField_9.Value;
            app.fmincon_options.Algorithm=value;
            value = app.EditField_10.Value;
            app.fmincon_options.display=value;
            app.fmincon_options.optimoptions=optimoptions(@fmincon,...
                'Algorithm',app.fmincon_options.Algorithm, ...
                'display',app.fmincon_options.display,...
                'TolCon',app.fmincon_options.TolCon,...
                'TolFun',app.fmincon_options.TolFun,...
                'MaxFunEvals',app.fmincon_options.MaxFunEvals,...
                'StepTolerance',app.fmincon_options.StepTolerance,...
                'MaxIter',app.fmincon_options.MaxIter,...
                'UseParallel',app.fmincon_options.parallel_flag); 
            
            app.saved_address = app.EditField.Value;
            
            value = app.KalmanFlagSwitch.Value;
            app.Kalman.flag=strcmp(value,'On');
            if app.Kalman.flag==1
                value=app.Kalman_Data_Input_Name.Value;
                Kalman_data_struct=load(value);
                app.Kalman=Kalman_data_struct.Kalman;
                
                value = str2func(app.Kalman_P_Input_Name.Value);
                app.Kalman.P_dynamics=@(t,q,auxdata) value(t,q,auxdata);     
            end   
            
            value = app.Plot_typeListBox.Value;
            app.plot_info.type=str2num(value);
            value = app.Plot_indxEditField.Value;
            app.plot_info.plot_index=value;        
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.saved_address = app.EditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Main_Window = uifigure('Visible', 'off');
            app.Main_Window.Name = 'MPC App';
            app.UIAxes = uiaxes(app.Main_Window);
            title(app.UIAxes, ' ')
            xlabel(app.UIAxes, ' ')
            ylabel(app.UIAxes, ' ')
            app.RunMPCButton = uibutton(app.Main_Window, 'push');
            app.RunMPCButton.ButtonPushedFcn = createCallbackFcn(app, @RunMPCButtonPushed, true);
            app.RunMPCButton.Text = 'Run MPC';
            app.Panel = uipanel(app.Main_Window);
            app.Panel.Title = ' ';
            app.Model_Data_Input_Name = uieditfield(app.Panel, 'text');
            app.Model_Data_Input_Name.ValueChangedFcn = createCallbackFcn(app, @Model_Data_Input_NameValueChanged, true);
            app.Model_Data_Input_Name.Value = 'Model_data';
            app.Panel_2 = uipanel(app.Main_Window);
            app.Panel_2.Title = ' ';
            app.DynamicHatLabel = uilabel(app.Panel_2);
            app.DynamicHatLabel.Text = '  ';
            app.Dynamic_Hat_Input_Name = uieditfield(app.Panel_2, 'text');
            app.Dynamic_Hat_Input_Name.ValueChangedFcn = createCallbackFcn(app, @Dynamic_Hat_Input_NameValueChanged, true);
            app.Dynamic_Hat_Input_Name.Value = 'Dynamic_hat';
            app.RealDynamicfLabel = uilabel(app.Panel_2);
            app.RealDynamicfLabel.Text = ' ';
            app.Dynamic_Real_Input_Name = uieditfield(app.Panel_2, 'text');
            app.Dynamic_Real_Input_Name.ValueChangedFcn = createCallbackFcn(app, @Dynamic_Real_Input_NameValueChanged, true);
            app.Dynamic_Real_Input_Name.Value = 'Dynamic_Real';
            app.PathConstLabel = uilabel(app.Panel_2);
            app.PathConstLabel.Text = ' ';
            app.Path_Const_Input_Name = uieditfield(app.Panel_2, 'text');
            app.Path_Const_Input_Name.ValueChangedFcn = createCallbackFcn(app, @Path_Const_Input_NameValueChanged, true);
            app.Path_Const_Input_Name.Value = 'Path_constraint';
            app.Image42 = uiimage(app.Panel_2);
            app.Image42.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Real_Syst.svg';
            app.Panel_3 = uipanel(app.Main_Window);
            app.Panel_3.Title = ' ';
            app.TsLabel = uilabel(app.Panel_3);
            app.TsLabel.Text = ' ';
            app.EditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 1;
            app.EditField_3Label = uilabel(app.Panel_3);
            app.EditField_3Label.Text = ' ';
            app.EditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 2;
            app.EditField_4Label = uilabel(app.Panel_3);
            app.EditField_4Label.Text = ' ';
            app.EditField_4 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = 2;
            app.EditField_5Label = uilabel(app.Panel_3);
            app.EditField_5Label.Text = ' ';
            app.EditField_5 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Value = 0.1;
            app.EditField_6Label = uilabel(app.Panel_3);
            app.EditField_6Label.Text = ' ';
            app.EditField_6 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Value = 0.1;
            app.EditField_7Label = uilabel(app.Panel_3);
            app.EditField_7Label.Text = ' ';
            app.EditField_7 = uieditfield(app.Panel_3, 'text');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_7.Value = '[0,-1]';
            app.DynamicSolverMethodLabel = uilabel(app.Panel_3);
            app.DynamicSolverMethodLabel.Text = ' ';
            app.ListBox = uilistbox(app.Panel_3);
            app.ListBox.Items = {'Euler', 'Runge-Kutta 4th'};
            app.ListBox.ItemsData = {'1', '2'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = '1';
            app.Image15 = uiimage(app.Panel_3);
            app.Image15.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Ts.svg';
            app.Image16 = uiimage(app.Panel_3);
            app.Image16.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/m.svg';
            app.Image17 = uiimage(app.Panel_3);
            app.Image17.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/p.svg';
            app.Image18 = uiimage(app.Panel_3);
            app.Image18.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/h.svg';
            app.Image19 = uiimage(app.Panel_3);
            app.Image19.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/h_ctr.svg';
            app.Image20 = uiimage(app.Panel_3);
            app.Image20.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/x0.svg';
            app.Image21 = uiimage(app.Panel_3);
            app.Image21.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Dyn_Solv_Method.svg';
            app.Panel_4 = uipanel(app.Main_Window);
            app.Panel_4.Title = ' ';
            app.EditField_8Label = uilabel(app.Panel_4);
            app.EditField_8Label.Text = ' ';
            app.EditField_8 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_8.Value = 1;
            app.EditField_9Label = uilabel(app.Panel_4);
            app.EditField_9Label.Text = ' ';
            app.EditField_9 = uieditfield(app.Panel_4, 'text');
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_9.Value = 'interior-point';
            app.EditField_10Label = uilabel(app.Panel_4);
            app.EditField_10Label.Text = ' ';
            app.EditField_10 = uieditfield(app.Panel_4, 'text');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_10.Value = 'iter';
            app.EditField_11Label = uilabel(app.Panel_4);
            app.EditField_11Label.Text = ' ';
            app.EditField_11 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_11.Value = 1e-09;
            app.EditField_12Label = uilabel(app.Panel_4);
            app.EditField_12Label.Text = '';
            app.EditField_12 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_12ValueChanged, true);
            app.EditField_12.Value = 1e-09;
            app.EditField_13Label = uilabel(app.Panel_4);
            app.EditField_13Label.Text = ' ';
            app.EditField_13 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @EditField_13ValueChanged, true);
            app.EditField_13.Value = 10000000;
            app.EditField_14Label = uilabel(app.Panel_4);
            app.EditField_14Label.Text = ' ';
            app.EditField_14 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @EditField_14ValueChanged, true);
            app.EditField_14.Value = 1e-06;
            app.EditField_15Label = uilabel(app.Panel_4);
            app.EditField_15Label.Text = ' ';
            app.EditField_15 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_15.ValueChangedFcn = createCallbackFcn(app, @EditField_15ValueChanged, true);
            app.EditField_15.Value = 100;
            app.Image22 = uiimage(app.Panel_4);
            app.Image22.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Core.svg';
            app.Image23 = uiimage(app.Panel_4);
            app.Image23.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Algorithm.svg';
            app.Image24 = uiimage(app.Panel_4);
            app.Image24.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Display.svg';
            app.Image27 = uiimage(app.Panel_4);
            app.Image27.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/MaxfunEval.svg';
            app.Image28 = uiimage(app.Panel_4);
            app.Image28.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/StepTol.svg';
            app.Image29 = uiimage(app.Panel_4);
            app.Image29.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/MaxIter.svg';
            app.Image25 = uiimage(app.Panel_4);
            app.Image25.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/TolFunc.svg';
            app.Image43 = uiimage(app.Panel_4);
            app.Image43.ImageSource = 'TolCon.svg';
            app.Panel_5 = uipanel(app.Main_Window);
            app.Panel_5.Title = ' ';
            app.KalmanFlagSwitch = uiswitch(app.Panel_5, 'slider');
            app.KalmanFlagSwitch.ValueChangedFcn = createCallbackFcn(app, @KalmanFlagSwitchValueChanged, true);
            app.Label_4 = uilabel(app.Panel_5);
            app.Label_4.Text = ' ';
            app.Kalman_Data_Input_Name = uieditfield(app.Panel_5, 'text');
            app.Kalman_Data_Input_Name.Value = 'Kalman_data';
            app.Kalman_P_Input_Name = uieditfield(app.Panel_5, 'text');
            app.Kalman_P_Input_Name.Value = 'Kalman_P_func';
            app.Label_3 = uilabel(app.Panel_5);
            app.Label_3.Text = ' ';
            app.Label_5 = uilabel(app.Panel_5);
            app.Label_5.Text = ' ';
            app.Image30 = uiimage(app.Panel_5);
            app.Image30.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Kalman_Data.svg';
            app.Panel_7 = uipanel(app.Main_Window);
            app.Panel_7.Title = ' ';
            app.Plot_typeListBox = uilistbox(app.Panel_7);
            app.Plot_typeListBox.Items = {'Control', 'State- Real', 'State- Hat'};
            app.Plot_typeListBox.ItemsData = {'0', '1', '2'};
            app.Plot_typeListBox.ValueChangedFcn = createCallbackFcn(app, @Plot_typeListBoxValueChanged, true);
            app.Plot_typeListBox.Value = '0';
            app.Label = uilabel(app.Panel_7);
            app.Label.Text = ' ';
            app.Plot_indxEditField = uieditfield(app.Panel_7, 'numeric');
            app.Plot_indxEditField.ValueChangedFcn = createCallbackFcn(app, @Plot_indxEditFieldValueChanged, true);
            app.Plot_indxEditField.Value = 1;
            app.Label_2 = uilabel(app.Panel_7);
            app.Label_2.Text = ' ';
            app.Image13 = uiimage(app.Panel_7);
            app.Image13.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Y-Axis.svg';
            app.Image14 = uiimage(app.Panel_7);
            app.Image14.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Index.svg';
            app.UpdateAllParametersButton = uibutton(app.Main_Window, 'push');
            app.UpdateAllParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAllParametersButtonPushed, true);
            app.UpdateAllParametersButton.Text = 'Update All Parameters ';
            app.Panel_6 = uipanel(app.Main_Window);
            app.Panel_6.Title = ' ';
            app.EditField = uieditfield(app.Panel_6, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 'Saved_Result';
            app.PlotButton = uibutton(app.Main_Window, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot ';
            app.Image2 = uiimage(app.Main_Window);
            app.Image2.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Model Data.svg';
            app.Image3 = uiimage(app.Main_Window);
            app.Image3.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Dynamic_and_Path.svg';
            app.Image4 = uiimage(app.Main_Window);
            app.Image4.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/MPC_Params.svg';
            app.Image5 = uiimage(app.Main_Window);
            app.Image5.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Fmincon.svg';
            app.Image6 = uiimage(app.Main_Window);
            app.Image6.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Kalman.svg';
            app.Image7 = uiimage(app.Main_Window);
            app.Image7.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Save_Result.svg';
            app.Image9 = uiimage(app.Main_Window);
            app.Image9.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Time.svg';
            app.Image11 = uiimage(app.Main_Window);
            app.Image11.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/State_Control_Bold.svg';
            app.Image12 = uiimage(app.Main_Window);
            app.Image12.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Plot.svg';
            app.Image31 = uiimage(app.Main_Window);
            app.Image31.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Klaman_Flag.svg';
            app.Image32 = uiimage(app.Main_Window);
            app.Image32.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Kalman_P_func.svg';
            app.Image33 = uiimage(app.Main_Window);
            app.Image33.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Path_Constraint.svg';
            app.Image38 = uiimage(app.Main_Window);
            app.Image38.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/University-Wordmark-Full-Color-RGB.png';
            app.Image39 = uiimage(app.Main_Window);
            app.Image39.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Email.svg';
            app.Image41 = uiimage(app.Main_Window);
            app.Image41.ImageSource = '/Users/bayat2/MATLAB-Drive/MPC_App/Model_Syst.svg';
            % Show the figure after all components are created
            app.Main_Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MPC_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Main_Window)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Main_Window)
        end
    end
end